classdef App_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        InicioMenu                     matlab.ui.container.Menu
        ProyectoMenu                   matlab.ui.container.Menu
        ConfiguracinMenu               matlab.ui.container.Menu
        PluginsMenu                    matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        OpenConfigButton               matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ExperimentoPanel               matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SolverDropDown                 matlab.ui.control.DropDown
        SolverDropDownLabel            matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        NombreEditField                matlab.ui.control.EditField
        NombreLabel                    matlab.ui.control.Label
        DescripcinLabel                matlab.ui.control.Label
        TiempoEditField                matlab.ui.control.NumericEditField
        DescripcinLabel_2              matlab.ui.control.Label
        SensorPanel                    matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        PosicindelplanoDropDown        matlab.ui.control.DropDown
        PosicindelplanoDropDownLabel   matlab.ui.control.Label
        ConfigurarPosicindelsensorButton  matlab.ui.control.Button
        VariablesDropDown              matlab.ui.control.DropDown
        VariablesDropDownLabel         matlab.ui.control.Label
        PlanoDropDown                  matlab.ui.control.DropDown
        PlanoDropDownLabel             matlab.ui.control.Label
        TipoDropDown_2                 matlab.ui.control.DropDown
        TipoDropDown_2Label            matlab.ui.control.Label
        TransductoremisorPanel         matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        ConfigurarTransductorButton    matlab.ui.control.Button
        MPaLabel                       matlab.ui.control.Label
        TipoDropDown                   matlab.ui.control.DropDown
        TipoDropDownLabel              matlab.ui.control.Label
        MHzLabel                       matlab.ui.control.Label
        NciclosEditField               matlab.ui.control.NumericEditField
        NciclosEditFieldLabel          matlab.ui.control.Label
        AmplitudEditField_2            matlab.ui.control.NumericEditField
        AmplitudEditField_2Label       matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        MododehazDropDown              matlab.ui.control.DropDown
        MododehazLabel                 matlab.ui.control.Label
        SealDropDown                   matlab.ui.control.DropDown
        SealDropDownLabel              matlab.ui.control.Label
        MedioAcusticoPanel             matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        ConfigurarMedioAcusticoButton  matlab.ui.control.Button
        kgmLabel                       matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        DensidadEditField              matlab.ui.control.NumericEditField
        DensidadEditFieldLabel         matlab.ui.control.Label
        VelsonidoEditField             matlab.ui.control.NumericEditField
        VelsonidoEditFieldLabel        matlab.ui.control.Label
        ModeloDropDown                 matlab.ui.control.DropDown
        ModeloDropDownLabel            matlab.ui.control.Label
        DensidadStdEditFieldLabel      matlab.ui.control.Label
        DensidadStdEditField           matlab.ui.control.NumericEditField
        DensidadStdUnitLabel           matlab.ui.control.Label
        GeometriayMallaPanel           matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridSizeEditField              matlab.ui.control.EditField
        GridSizeEditFieldLabel         matlab.ui.control.Label
        CFLEditField                   matlab.ui.control.NumericEditField
        CFLEditFieldLabel              matlab.ui.control.Label
        PMLCantcapasEditField          matlab.ui.control.NumericEditField
        PMLCantcapasEditFieldLabel     matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        ResolucinEditField             matlab.ui.control.NumericEditField
        ResolucinEditFieldLabel        matlab.ui.control.Label
        DimensionesEditField           matlab.ui.control.NumericEditField
        DimensionesEditFieldLabel      matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        PreviewPanel                   matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        RealizacionesporreferenciaSpinner  matlab.ui.control.Spinner
        RealizacionesporreferenciaSpinnerLabel  matlab.ui.control.Label
        NmeroderealizacionesSpinner    matlab.ui.control.Spinner
        NmeroderealizacionesSpinnerLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        PruebaButton                   matlab.ui.control.Button
        EjecucinPanel                  matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        GridLayout15                   matlab.ui.container.GridLayout
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        GeometricoTab                  matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        TimeScope4                     matlab.ui.scope.TimeScope
        TimeScope3                     matlab.ui.scope.TimeScope
        PropiedadesTab                 matlab.ui.container.Tab
        GridLayout10_3                 matlab.ui.container.GridLayout
        TimeScope_3                    matlab.ui.scope.TimeScope
        TimeScope2_2                   matlab.ui.scope.TimeScope
        TimeScope_2                    matlab.ui.scope.TimeScope
        SealTab                        matlab.ui.container.Tab
        GridLayout10_2                 matlab.ui.container.GridLayout
        TimeScope2                     matlab.ui.scope.TimeScope
        TimeScope                      matlab.ui.scope.TimeScope
        GridLayout12                   matlab.ui.container.GridLayout
        EstadodelExperimentoPanel      matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        CambiosencolaEditField         matlab.ui.control.EditField
        CambiosencolaEditFieldLabel    matlab.ui.control.Label
        ConfiguracinEditField          matlab.ui.control.EditField
        ConfiguracinEditFieldLabel     matlab.ui.control.Label
        ModoEditField                  matlab.ui.control.EditField
        ModoEditFieldLabel             matlab.ui.control.Label
        EstadoEditField                matlab.ui.control.EditField
        EstadoEditFieldLabel           matlab.ui.control.Label
        ExploradordeexperimentoPanel   matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        AbrirCarpetaButton_2           matlab.ui.control.Button
        RutadesalidaEditField          matlab.ui.control.EditField
        RutadesalidaEditFieldLabel     matlab.ui.control.Label
        ExperimentoActualEditField     matlab.ui.control.EditField
        ExperimentoActualEditFieldLabel  matlab.ui.control.Label
        RepositorioEditField           matlab.ui.control.EditField
        RepositorioEditFieldLabel      matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        TreeNode                       matlab.ui.container.TreeNode
        CopiarrutaButton_2             matlab.ui.control.Button
        AtrasButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ProjectExplorerLogic.initialize(app);
            SensorPanelLogic.initialize(app);
            PreviewLogic.initialize(app);
            QUSConfigurationLogic.onTest(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            QUSConfigurationLogic.onSet(app);
        end

        % Button pushed function: PruebaButton
        function PruebaButtonPushed(app, event)
            QUSConfigurationLogic.onTest(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            QUSConfigurationLogic.onReset(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            QUSConfigurationLogic.onSave(app);
        end

        % Button pushed function: OpenConfigButton
        function OpenConfigButtonPushed(app, event)
            QUSConfigurationLogic.onOpen(app);
        end

        % Button pushed function: ConfigurarMedioAcusticoButton
        function ConfigurarMedioAcusticoButtonPushed(app, event)
            QUSConfigurationLogic.openMediumSettings(app);
        end

        % Button pushed function: ConfigurarTransductorButton
        function ConfigurarTransductorButtonPushed(app, event)
            QUSConfigurationLogic.openTransducerSettings(app);
        end

        % Button pushed function: ConfigurarPosicindelsensorButton
        function ConfigurarPosicindelsensorButtonPushed(app, event)
            QUSConfigurationLogic.openSensorSettings(app);
        end

        % Menu selected function: ConfiguracinMenu
        function ConfiguracinMenuSelected(app, event)
            QUSConfigurationLogic.openPipelineSettings(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            ProjectExplorerLogic.showSelectedPathAsTooltip(app);
        end

        % Button pushed function: AtrasButton
        function AtrasButtonPushed(app, event)
            ProjectExplorerLogic.goBack(app);
        end

        % Button pushed function: CopiarrutaButton_2
        function CopiarrutaButton_2Pushed(app, event)
            ProjectExplorerLogic.copySelectedPath(app);
        end

        % Value changed function: NombreEditField
        function NombreEditFieldValueChanged(app, event)
            ProjectExplorerLogic.updateCurrentExperiment(app);
            PreviewLogic.refreshIfAvailable(app);
        end

        % Callback function
        function RepositorioEditFieldValueChanged(app, event)
            value = app.RepositorioEditField.Value;
            
        end

        % Callback function
        function RepositorioEditFieldValueChanged2(app, event)
            value = app.RepositorioEditField.Value;
            
        end

        % Callback function
        function RutadesalidaEditFieldValueChanged(app, event)
            value = app.RutadesalidaEditField.Value;
            
        end

        % Button pushed function: AbrirCarpetaButton_2
        function AbrirCarpetaButton_2Pushed(app, event)
            ProjectExplorerLogic.openSelectedFolder(app);
        end

        % Callback function
        function AbrirCarpetaButton_2Pushed2(app, event)
            
        end

        % Callback function
        function AbrirCarpetaButton_2Pushed3(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.InicioMenu = uimenu(app.UIFigure);
            app.InicioMenu.Text = 'Inicio';
            app.ProyectoMenu = uimenu(app.UIFigure);
            app.ProyectoMenu.Text = 'Proyecto';
            app.ConfiguracinMenu = uimenu(app.UIFigure);
            app.ConfiguracinMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguracinMenuSelected, true);
            app.ConfiguracinMenu.Text = 'Configuración';
            app.PluginsMenu = uimenu(app.UIFigure);
            app.PluginsMenu.Text = 'Plugins';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.ExploradordeexperimentoPanel = uipanel(app.GridLayout12);
            app.ExploradordeexperimentoPanel.Title = 'Explorador de experimento';
            app.ExploradordeexperimentoPanel.Tag = 'ResultadosPanel';
            app.GridLayout14 = uigridlayout(app.ExploradordeexperimentoPanel);
            app.AtrasButton = uibutton(app.GridLayout14, 'push');
            app.AtrasButton.ButtonPushedFcn = createCallbackFcn(app, @AtrasButtonPushed, true);
            app.AtrasButton.Text = 'Atras';
            app.CopiarrutaButton_2 = uibutton(app.GridLayout14, 'push');
            app.CopiarrutaButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopiarrutaButton_2Pushed, true);
            app.CopiarrutaButton_2.Text = {'Copiar'; 'ruta'};
            app.Tree = uitree(app.GridLayout14);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.TreeNode = uitreenode(app.Tree);
            app.TreeNode.Text = 'Tree';
            app.RepositorioEditFieldLabel = uilabel(app.GridLayout14);
            app.RepositorioEditFieldLabel.Text = 'Repositorio:  ';
            app.RepositorioEditField = uieditfield(app.GridLayout14, 'text');
            app.ExperimentoActualEditFieldLabel = uilabel(app.GridLayout14);
            app.ExperimentoActualEditFieldLabel.Text = 'Experimento Actual:';
            app.ExperimentoActualEditField = uieditfield(app.GridLayout14, 'text');
            app.RutadesalidaEditFieldLabel = uilabel(app.GridLayout14);
            app.RutadesalidaEditFieldLabel.Text = 'Ruta de salida: ';
            app.RutadesalidaEditField = uieditfield(app.GridLayout14, 'text');
            app.AbrirCarpetaButton_2 = uibutton(app.GridLayout14, 'push');
            app.AbrirCarpetaButton_2.ButtonPushedFcn = createCallbackFcn(app, @AbrirCarpetaButton_2Pushed, true);
            app.AbrirCarpetaButton_2.Text = {'Abrir '; 'Carpeta'};
            app.EstadodelExperimentoPanel = uipanel(app.GridLayout12);
            app.EstadodelExperimentoPanel.Title = 'Estado del Experimento';
            app.GridLayout13 = uigridlayout(app.EstadodelExperimentoPanel);
            app.EstadoEditFieldLabel = uilabel(app.GridLayout13);
            app.EstadoEditFieldLabel.Text = 'Estado: ';
            app.EstadoEditField = uieditfield(app.GridLayout13, 'text');
            app.ModoEditFieldLabel = uilabel(app.GridLayout13);
            app.ModoEditFieldLabel.Text = 'Modo';
            app.ModoEditField = uieditfield(app.GridLayout13, 'text');
            app.ConfiguracinEditFieldLabel = uilabel(app.GridLayout13);
            app.ConfiguracinEditFieldLabel.Text = 'Configuración:';
            app.ConfiguracinEditField = uieditfield(app.GridLayout13, 'text');
            app.CambiosencolaEditFieldLabel = uilabel(app.GridLayout13);
            app.CambiosencolaEditFieldLabel.Text = 'Cambios en cola';
            app.CambiosencolaEditField = uieditfield(app.GridLayout13, 'text');
            app.PreviewPanel = uipanel(app.GridLayout);
            app.PreviewPanel.Title = 'Preview';
            app.PreviewPanel.Tag = 'ExperimentosPanel';
            app.GridLayout9 = uigridlayout(app.PreviewPanel);
            app.GridLayout9.Tag = 'ExperimentosTabGroup';
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.TabGroup.Tag = 'ExperimentosTabGroup';
            app.GeometricoTab = uitab(app.TabGroup);
            app.GeometricoTab.Title = 'Geometrico';
            app.GridLayout10 = uigridlayout(app.GeometricoTab);
            app.TimeScope3 = uitimescope(app.GridLayout10);
            app.TimeScope3.Title = 'Title';
            app.TimeScope3.YLabel = 'Amplitude';
            app.TimeScope4 = uitimescope(app.GridLayout10);
            app.TimeScope4.Title = 'Title';
            app.TimeScope4.YLabel = 'Amplitude';
            app.PropiedadesTab = uitab(app.TabGroup);
            app.PropiedadesTab.Title = 'Propiedades';
            app.GridLayout10_3 = uigridlayout(app.PropiedadesTab);
            app.TimeScope_2 = uitimescope(app.GridLayout10_3);
            app.TimeScope_2.Title = 'Title';
            app.TimeScope_2.YLabel = 'Amplitude';
            app.TimeScope2_2 = uitimescope(app.GridLayout10_3);
            app.TimeScope2_2.Title = 'Title';
            app.TimeScope2_2.YLabel = 'Amplitude';
            app.TimeScope_3 = uitimescope(app.GridLayout10_3);
            app.TimeScope_3.Title = 'Title';
            app.TimeScope_3.YLabel = 'Amplitude';
            app.SealTab = uitab(app.TabGroup);
            app.SealTab.Title = 'Señal';
            app.GridLayout10_2 = uigridlayout(app.SealTab);
            app.TimeScope = uitimescope(app.GridLayout10_2);
            app.TimeScope.Title = 'Title';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope2 = uitimescope(app.GridLayout10_2);
            app.TimeScope2.Title = 'Title';
            app.TimeScope2.YLabel = 'Amplitude';
            app.EjecucinPanel = uipanel(app.GridLayout9);
            app.EjecucinPanel.Title = 'Ejecución';
            app.EjecucinPanel.Tag = 'BitacoraPanel';
            app.GridLayout11 = uigridlayout(app.EjecucinPanel);
            app.TextAreaLabel = uilabel(app.GridLayout11);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.GridLayout11);
            app.TextArea.Tag = 'LogsTextArea';
            app.TextArea.Value = {'>> '; '[LOG DE EJECUCIÓN GRANDE]'; 'Running simulation...'; 'Grid: ...'; 'Medium: ...'; 'Time step: ...'; 'Simulation 45%'; 'Saving results...'};
            app.GridLayout15 = uigridlayout(app.GridLayout11);
            app.GridLayout16 = uigridlayout(app.GridLayout9);
            app.PruebaButton = uibutton(app.GridLayout16, 'push');
            app.PruebaButton.ButtonPushedFcn = createCallbackFcn(app, @PruebaButtonPushed, true);
            app.PruebaButton.Enable = 'off';
            app.PruebaButton.Text = 'Delete';
            app.ResetButton = uibutton(app.GridLayout16, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NmeroderealizacionesSpinnerLabel = uilabel(app.GridLayout16);
            app.NmeroderealizacionesSpinnerLabel.Text = 'Número de realizaciones';
            app.NmeroderealizacionesSpinner = uispinner(app.GridLayout16);
            app.RealizacionesporreferenciaSpinnerLabel = uilabel(app.GridLayout16);
            app.RealizacionesporreferenciaSpinnerLabel.Text = 'Realizaciones por referencia';
            app.RealizacionesporreferenciaSpinner = uispinner(app.GridLayout16);
            app.DropDown = uidropdown(app.GridLayout16);
            app.DropDown.Items = {'Ref'};
            app.DropDown.Value = 'Ref';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Visible = 'off';
            app.GeometriayMallaPanel = uipanel(app.GridLayout2);
            app.GeometriayMallaPanel.Title = 'Geometria y Malla';
            app.GridLayout5 = uigridlayout(app.GeometriayMallaPanel);
            app.DimensionesEditFieldLabel = uilabel(app.GridLayout5);
            app.DimensionesEditFieldLabel.Text = 'Profundidad:';
            app.DimensionesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.DimensionesEditField.Value = 0.055;
            app.ResolucinEditFieldLabel = uilabel(app.GridLayout5);
            app.ResolucinEditFieldLabel.Text = 'PPW:';
            app.ResolucinEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ResolucinEditField.Value = 6;
            app.mLabel_2 = uilabel(app.GridLayout5);
            app.mLabel_2.Text = '[m]';
            app.mLabel_3 = uilabel(app.GridLayout5);
            app.mLabel_3.Text = '[m]';
            app.PMLCantcapasEditFieldLabel = uilabel(app.GridLayout5);
            app.PMLCantcapasEditFieldLabel.Text = 'PML (Cant. capas)';
            app.PMLCantcapasEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PMLCantcapasEditField.Value = 41;
            app.CFLEditFieldLabel = uilabel(app.GridLayout5);
            app.CFLEditFieldLabel.Text = 'CFL';
            app.CFLEditField = uieditfield(app.GridLayout5, 'numeric');
            app.CFLEditField.Value = 0.3;
            app.GridSizeEditFieldLabel = uilabel(app.GridLayout5);
            app.GridSizeEditFieldLabel.Text = 'Malla (z, x):';
            app.GridSizeEditField = uieditfield(app.GridLayout5, 'text');
            app.GridSizeEditField.Value = '(0.056,0.04)';
            app.MedioAcusticoPanel = uipanel(app.GridLayout2);
            app.MedioAcusticoPanel.Title = 'Medio Acustico';
            app.GridLayout8 = uigridlayout(app.MedioAcusticoPanel);
            app.DensidadStdUnitLabel = uilabel(app.GridLayout8);
            app.DensidadStdUnitLabel.Text = '[-]';
            app.DensidadStdEditField = uieditfield(app.GridLayout8, 'numeric');
            app.DensidadStdEditField.Value = 0.04;
            app.DensidadStdEditFieldLabel = uilabel(app.GridLayout8);
            app.DensidadStdEditFieldLabel.Text = 'Desv. densidad:';
            app.ModeloDropDownLabel = uilabel(app.GridLayout8);
            app.ModeloDropDownLabel.Text = 'Modelo';
            app.ModeloDropDown = uidropdown(app.GridLayout8);
            app.ModeloDropDown.Items = {'Homogeneo', 'Inclusión circular', 'Inclusión irregular', 'Medio por capas', 'Múltiples inclusiones'};
            app.ModeloDropDown.Value = 'Homogeneo';
            app.VelsonidoEditFieldLabel = uilabel(app.GridLayout8);
            app.VelsonidoEditFieldLabel.Text = 'Vel. sonido:';
            app.VelsonidoEditField = uieditfield(app.GridLayout8, 'numeric');
            app.VelsonidoEditField.Value = 1595;
            app.DensidadEditFieldLabel = uilabel(app.GridLayout8);
            app.DensidadEditFieldLabel.Text = {'Densidad:   '; ''};
            app.DensidadEditField = uieditfield(app.GridLayout8, 'numeric');
            app.DensidadEditField.Value = 1060;
            app.msLabel = uilabel(app.GridLayout8);
            app.msLabel.Text = '[m/s]';
            app.kgmLabel = uilabel(app.GridLayout8);
            app.kgmLabel.Text = '[kg/m³]';
            app.ConfigurarMedioAcusticoButton = uibutton(app.GridLayout8, 'push');
            app.ConfigurarMedioAcusticoButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurarMedioAcusticoButtonPushed, true);
            app.ConfigurarMedioAcusticoButton.Text = 'Configurar Medio Acustico';
            app.TransductoremisorPanel = uipanel(app.GridLayout2);
            app.TransductoremisorPanel.Title = 'Transductor emisor';
            app.GridLayout7 = uigridlayout(app.TransductoremisorPanel);
            app.SealDropDownLabel = uilabel(app.GridLayout7);
            app.SealDropDownLabel.Text = 'Señal:';
            app.SealDropDown = uidropdown(app.GridLayout7);
            app.SealDropDown.Items = {'Tone Burst', 'Continuous Wave', 'Initial Pressure', 'Personalizado', ''};
            app.SealDropDown.Enable = 'off';
            app.SealDropDown.Value = 'Tone Burst';
            app.MododehazLabel = uilabel(app.GridLayout7);
            app.MododehazLabel.Text = 'Modo de haz:';
            app.MododehazDropDown = uidropdown(app.GridLayout7);
            app.MododehazDropDown.Items = {'Focused', 'Plane Wave', 'PWC', 'Personalizado'};
            app.MododehazDropDown.Enable = 'off';
            app.MododehazDropDown.Value = 'Focused';
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout7);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout7, 'numeric');
            app.FrecuenciaEditField.Value = 6.66;
            app.AmplitudEditField_2Label = uilabel(app.GridLayout7);
            app.AmplitudEditField_2Label.Text = 'Amplitud:';
            app.AmplitudEditField_2 = uieditfield(app.GridLayout7, 'numeric');
            app.AmplitudEditField_2.Value = 1;
            app.NciclosEditFieldLabel = uilabel(app.GridLayout7);
            app.NciclosEditFieldLabel.Text = 'N.º ciclos: ';
            app.NciclosEditField = uieditfield(app.GridLayout7, 'numeric');
            app.NciclosEditField.Value = 3.5;
            app.MHzLabel = uilabel(app.GridLayout7);
            app.MHzLabel.Text = '[MHz]';
            app.TipoDropDownLabel = uilabel(app.GridLayout7);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.GridLayout7);
            app.TipoDropDown.Items = {'Lineal Plano', 'Elemento único plano', 'Arreglo convexo ', 'Personalizado', ''};
            app.TipoDropDown.Enable = 'off';
            app.TipoDropDown.Value = 'Lineal Plano';
            app.MPaLabel = uilabel(app.GridLayout7);
            app.MPaLabel.Text = '[MPa]';
            app.ConfigurarTransductorButton = uibutton(app.GridLayout7, 'push');
            app.ConfigurarTransductorButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurarTransductorButtonPushed, true);
            app.ConfigurarTransductorButton.Text = 'Configurar Transductor';
            app.SensorPanel = uipanel(app.GridLayout2);
            app.SensorPanel.Title = 'Cálculo y reproducibilidad';
            app.GridLayout6 = uigridlayout(app.SensorPanel);
            app.TipoDropDown_2Label = uilabel(app.GridLayout6);
            app.TipoDropDown_2Label.Text = 'DataCast:';
            app.TipoDropDown_2 = uidropdown(app.GridLayout6);
            app.TipoDropDown_2.Items = {'gpuArray-single', 'single'};
            app.TipoDropDown_2.Value = 'gpuArray-single';
            app.PlanoDropDownLabel = uilabel(app.GridLayout6);
            app.PlanoDropDownLabel.Text = 'PlotSim:';
            app.PlanoDropDown = uidropdown(app.GridLayout6);
            app.PlanoDropDown.Items = {'false', 'true'};
            app.PlanoDropDown.Value = 'false';
            app.VariablesDropDownLabel = uilabel(app.GridLayout6);
            app.VariablesDropDownLabel.Text = 'Semilla base:';
            app.VariablesDropDown = uidropdown(app.GridLayout6);
            app.VariablesDropDown.Items = {'50000'};
            app.VariablesDropDown.Enable = 'off';
            app.VariablesDropDown.Value = '50000';
            app.ConfigurarPosicindelsensorButton = uibutton(app.GridLayout6, 'push');
            app.ConfigurarPosicindelsensorButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurarPosicindelsensorButtonPushed, true);
            app.ConfigurarPosicindelsensorButton.Text = 'Configurar Pipeline';
            app.PosicindelplanoDropDownLabel = uilabel(app.GridLayout6);
            app.PosicindelplanoDropDownLabel.Text = 'Semilla global:';
            app.PosicindelplanoDropDown = uidropdown(app.GridLayout6);
            app.PosicindelplanoDropDown.Items = {'23'};
            app.PosicindelplanoDropDown.Enable = 'off';
            app.PosicindelplanoDropDown.Value = '23';
            app.ExperimentoPanel = uipanel(app.GridLayout2);
            app.ExperimentoPanel.Title = 'Experimento';
            app.GridLayout4 = uigridlayout(app.ExperimentoPanel);
            app.DescripcinLabel_2 = uilabel(app.GridLayout4);
            app.DescripcinLabel_2.Text = '[µs]';
            app.TiempoEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TiempoEditField.Editable = 'off';
            app.TiempoEditField.Value = 68.9655;
            app.DescripcinLabel = uilabel(app.GridLayout4);
            app.DescripcinLabel.Text = 'Tiempo calculado:';
            app.NombreLabel = uilabel(app.GridLayout4);
            app.NombreLabel.Text = 'Nombre:';
            app.NombreEditField = uieditfield(app.GridLayout4, 'text');
            app.NombreEditField.ValueChangedFcn = createCallbackFcn(app, @NombreEditFieldValueChanged, true);
            app.NombreEditField.Placeholder = 'Exp_1_lIM';
            app.NombreEditField.Value = 'homogeneous_benchmark';
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '';
            app.SolverDropDownLabel = uilabel(app.GridLayout4);
            app.SolverDropDownLabel.Text = 'Solver:';
            app.SolverDropDown = uidropdown(app.GridLayout4);
            app.SolverDropDown.Items = {'kspaceFirstoOrder2D', 'kspaceFirstoOrder3D', ''};
            app.SolverDropDown.Value = 'kspaceFirstoOrder2D';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SetButton = uibutton(app.GridLayout3, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.OpenConfigButton = uibutton(app.GridLayout3, 'push');
            app.OpenConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenConfigButtonPushed, true);
            app.OpenConfigButton.Text = 'Open Config';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end